classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Slider_3  matlab.ui.control.Slider
        Slider_2  matlab.ui.control.Slider
        Slider    matlab.ui.control.Slider
        UIAxes2   matlab.ui.control.UIAxes
        UIAxes_3  matlab.ui.control.UIAxes
        UIAxes_2  matlab.ui.control.UIAxes
        UIAxes    matlab.ui.control.UIAxes
    end

    
    methods (Static)
        function [V] = matrix(~)
x = 300;
y = 280;
z = 401;

str = int2str(z);
n = strlength(str);

V = zeros(x,y,z);

for i = 1:z
    pStr = 'D:\3d\';
    m = floor(log10(i))+1;
    if m < n
        for j = m:n-1
            pStr = strcat(pStr,'0');
        end
    end
    pStr = strcat(pStr,sprintf('%d.txt',i));
    V(:,:,i) = dlmread(pStr);
end
end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            handle(app.UIAxes);
            value = int8(value);
            slice(matrix(app), value, [], []);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes_2 = uiaxes(app.UIFigure);
            app.UIAxes_3 = uiaxes(app.UIFigure);
            app.UIAxes2 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [-15 15];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider_2 = uislider(app.UIFigure);
            app.Slider_2.Limits = [-14 14];
            app.Slider_3 = uislider(app.UIFigure);
            app.Slider_3.Limits = [-2 38];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end