classdef ready_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        clr_4              matlab.ui.control.Button
        clr_3              matlab.ui.control.Button
        clr_1              matlab.ui.control.Button
        ZsliceSlider       matlab.ui.control.Slider
        ZsliceSliderLabel  matlab.ui.control.Label
        YsliceSlider       matlab.ui.control.Slider
        YsliceSliderLabel  matlab.ui.control.Label
        XsliceSlider       matlab.ui.control.Slider
        XsliceSliderLabel  matlab.ui.control.Label
        clr_0              matlab.ui.control.Button
        zaxis              matlab.ui.control.UIAxes
        xaxis              matlab.ui.control.UIAxes
        yaxis              matlab.ui.control.UIAxes
        fullaxes           matlab.ui.control.UIAxes
    end


    properties (Access = public)
        V;
        x_3 = -15:0.1:14.9;
        y_3 = -14:0.1:13.9;
        z_3 = -2:0.1:38;
        x_i = 14.9:-0.1:-15;
        y_i = 13.9:-0.1:-14;
        z_i = 38:-0.1:-2;
        color_0 = [0.9290 0.6940 0.1250];
        color_1 = [0 0.4470 0.7410];
        color_2 = [1 0 0];
        color_3 = [0.4940 0.1840 0.5560];
    end

    methods (Access = public)

        function V = matrix(app)
            x = 300;
            y = 280;
            z = 401;

            str = int2str(z);
            n = strlength(str);

            V = zeros(x,y,z);

            for i = 1:z
                pStr = 'D:\3d\';
                m = floor(log10(i))+1;
                if m < n
                    for j = m:n-1
                        pStr = strcat(pStr,'0');
                    end
                end
                pStr = strcat(pStr,sprintf('%d.txt',i));
                V(:,:,i) = dlmread(pStr);
            end
        end

        function [] = redraw(app)
            attractor1 = readtable("attractor_1.xlsx");
            attractor2 = readtable("attractor_2.xlsx");

            x_1 = attractor1.Amplitude_Plot0(1:10000);
            x_2 = attractor2.Amplitude_Plot0(1:10000);
            y_1 = attractor1.Amplitude_Plot1(1:10000);
            y_2 = attractor2.Amplitude_Plot1(1:10000);
            z_1 = attractor1.Amplitude_Plot2(1:10000);
            z_2 = attractor2.Amplitude_Plot2(1:10000);
            
            x = round(app.XsliceSlider.Value, 1);
            y = round(app.YsliceSlider.Value, 1);
            z = round(app.ZsliceSlider.Value, 1);

            app.V = matrix(app);

            map = cat(1, app.color_0, app.color_1, app.color_0, app.color_3, app.color_2);
            imagemap = cat(1, app.color_0, app.color_1, app.color_3, app.color_2);
            
            xi = (x+15.1)*10;
            yi = (y+14.1)*10;
            zi = (z+2.1)*10;

            Vx = app.V(151,:,:);
            Vx = squeeze(Vx);
            Vx = Vx.*2;
            Vy = app.V(:,141,:);
            Vy = squeeze(Vy);
            Vy = Vy.*2;
            Vz = app.V(:,:,121);
            Vz = squeeze(Vz);
            Vz = Vz.*2;

            plot3(app.fullaxes, y_1, x_1, z_1, 'Color', app.color_3);
            hold(app.fullaxes,'on');
            colormap(app.fullaxes, map);
            s = slice(app.fullaxes, app.y_3, app.x_3, app.z_3, app.V, x,y,z);
            set(s, 'EdgeAlpha',0.1);
            plot3(app.fullaxes, y_2, x_2, z_2, 'Color', app.color_2);
            hold(app.fullaxes,'off');

            colormap(app.xaxis, imagemap);
            colormap(app.yaxis, imagemap);
            colormap(app.zaxis, imagemap);
            image(app.xaxis, app.y_3, app.z_i, Vx);
            image(app.yaxis, app.x_3, app.z_i, Vy);
            image(app.zaxis, app.x_3, app.y_i, Vz);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            redraw(app);
        end

        % Button pushed function: clr_0
        function clr_0Pushed(app, event)
            app.color_0 = uisetcolor;
            redraw(app);
        end

        % Value changed function: XsliceSlider
        function XsliceSliderValueChanged(app, event)
            redraw(app);
        end

        % Value changed function: YsliceSlider
        function YsliceSliderValueChanged(app, event)
            redraw(app);
        end

        % Value changed function: ZsliceSlider
        function ZsliceSliderValueChanged(app, event)
            redraw(app);
        end

        % Button pushed function: clr_1
        function clr_1ButtonPushed(app, event)
            app.color_1 = uisetcolor;
            redraw(app);
        end

        % Button pushed function: clr_3
        function clr_3ButtonPushed(app, event)
            app.color_2 = uisetcolor;
            redraw(app);
        end

        % Button pushed function: clr_4
        function clr_4ButtonPushed(app, event)
            app.color_3 = uisetcolor;
            redraw(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.fullaxes = uiaxes(app.UIFigure);
            title(app.fullaxes, '3D Plot')
            xlabel(app.fullaxes, 'X')
            ylabel(app.fullaxes, 'Y')
            zlabel(app.fullaxes, 'Z')
            app.yaxis = uiaxes(app.UIFigure);
            title(app.yaxis, 'Y-slice')
            xlabel(app.yaxis, 'X')
            ylabel(app.yaxis, 'Y')
            zlabel(app.yaxis, 'Z')
            app.xaxis = uiaxes(app.UIFigure);
            title(app.xaxis, 'X-slice')
            xlabel(app.xaxis, 'X')
            ylabel(app.xaxis, 'Y')
            zlabel(app.xaxis, 'Z')
            app.zaxis = uiaxes(app.UIFigure);
            title(app.zaxis, 'Z-slice')
            xlabel(app.zaxis, 'X')
            ylabel(app.zaxis, 'Y')
            zlabel(app.zaxis, 'Z')
            app.clr_0 = uibutton(app.UIFigure, 'push');
            app.clr_0.ButtonPushedFcn = createCallbackFcn(app, @clr_0Pushed, true);
            app.clr_0.Text = 'Color 1';
            app.XsliceSliderLabel = uilabel(app.UIFigure);
            app.XsliceSliderLabel.Text = 'X-slice';
            app.XsliceSlider = uislider(app.UIFigure);
            app.XsliceSlider.Limits = [-15 14.9];
            app.XsliceSlider.ValueChangedFcn = createCallbackFcn(app, @XsliceSliderValueChanged, true);
            app.YsliceSliderLabel = uilabel(app.UIFigure);
            app.YsliceSliderLabel.Text = 'Y-slice';
            app.YsliceSlider = uislider(app.UIFigure);
            app.YsliceSlider.Limits = [-14 13.9];
            app.YsliceSlider.ValueChangedFcn = createCallbackFcn(app, @YsliceSliderValueChanged, true);
            app.ZsliceSliderLabel = uilabel(app.UIFigure);
            app.ZsliceSliderLabel.Text = 'Z-slice';
            app.ZsliceSlider = uislider(app.UIFigure);
            app.ZsliceSlider.Limits = [-2 38];
            app.ZsliceSlider.ValueChangedFcn = createCallbackFcn(app, @ZsliceSliderValueChanged, true);
            app.ZsliceSlider.Value = 10;
            app.clr_1 = uibutton(app.UIFigure, 'push');
            app.clr_1.ButtonPushedFcn = createCallbackFcn(app, @clr_1ButtonPushed, true);
            app.clr_1.Text = 'Color 2';
            app.clr_3 = uibutton(app.UIFigure, 'push');
            app.clr_3.ButtonPushedFcn = createCallbackFcn(app, @clr_3ButtonPushed, true);
            app.clr_3.Text = 'Color 3';
            app.clr_4 = uibutton(app.UIFigure, 'push');
            app.clr_4.ButtonPushedFcn = createCallbackFcn(app, @clr_4ButtonPushed, true);
            app.clr_4.Text = 'Color 4';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ready_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end