classdef try_0 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Button    matlab.ui.control.Button
        zaxis     matlab.ui.control.UIAxes
        xaxis     matlab.ui.control.UIAxes
        yaxis     matlab.ui.control.UIAxes
        fullaxes  matlab.ui.control.UIAxes
    end


    properties (Access = public)
        V;
        x_3 = -15:0.1:14.9;
        y_3 = -14:0.1:13.9;
        z_3 = -2:0.1:38;
        x_i = 14.9:-0.1:-15;
        y_i = 13.9:-0.1:-14;
        z_i = 38:-0.1:-2;
        color_0 = [0.9290 0.6940 0.1250];
        color_1 = [0 0.4470 0.7410];
        color_2 = [1 0 0];
        color_3 = [0.4940 0.1840 0.5560];
    end

    methods (Access = public)

        function V = matrix(app)
            x = 300;
            y = 280;
            z = 401;

            str = int2str(z);
            n = strlength(str);

            V = zeros(x,y,z);

            for i = 1:z
                pStr = 'D:\3d\';
                m = floor(log10(i))+1;
                if m < n
                    for j = m:n-1
                        pStr = strcat(pStr,'0');
                    end
                end
                pStr = strcat(pStr,sprintf('%d.txt',i));
                V(:,:,i) = dlmread(pStr);
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            attractor1 = readtable("attractor_1.xlsx");
            attractor2 = readtable("attractor_2.xlsx");

            x_1 = attractor1.Amplitude_Plot0(1:10000);
            x_2 = attractor2.Amplitude_Plot0(1:10000);
            y_1 = attractor1.Amplitude_Plot1(1:10000);
            y_2 = attractor2.Amplitude_Plot1(1:10000);
            z_1 = attractor1.Amplitude_Plot2(1:10000);
            z_2 = attractor2.Amplitude_Plot2(1:10000);

            app.V = matrix(app);

            map = [
                app.color_0
                app.color_1
                app.color_0
                app.color_2
                app.color_3
                ];

            imagemap = [
                app.color_0
                app.color_1
                app.color_2
                app.color_3
                ];

            x = 0; y = 0; z = 10;

            xi = (x+15.1)*10;
            yi = (y+14.1)*10;
            zi = (z+2.1)*10;

            Vx = app.V(151,:,:);
            Vx = squeeze(Vx);
            Vx = Vx.*2;
            Vy = app.V(:,141,:);
            Vy = squeeze(Vy);
            Vy = Vy.*2;
            Vz = app.V(:,:,121);
            Vz = squeeze(Vz);
            Vz = Vz.*2;

            hold on;
            colormap(app.fullaxes, map);
            s = slice(app.fullaxes, app.y_3, app.x_3, app.z_3, app.V, 0,0,15);
            set(s, 'EdgeAlpha',0.1);
            plot3(app.fullaxes, y_1, x_1, z_1, 'Color', app.color_2);
            %plot3(app.fullaxes, y_2, x_2, z_2, 'Color', app.color_3);
            hold off;

            colormap(app.xaxis, imagemap);
            colormap(app.yaxis, imagemap);
            colormap(app.zaxis, imagemap);
            image(app.xaxis, app.y_3, app.z_i, Vx);
            image(app.yaxis, app.x_3, app.z_i, Vy);
            image(app.zaxis, app.x_3, app.y_i, Vz);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.fullaxes = uiaxes(app.UIFigure);
            title(app.fullaxes, '3D Plot')
            xlabel(app.fullaxes, 'X')
            ylabel(app.fullaxes, 'Y')
            zlabel(app.fullaxes, 'Z')
            app.yaxis = uiaxes(app.UIFigure);
            title(app.yaxis, 'Y-slice')
            xlabel(app.yaxis, 'X')
            ylabel(app.yaxis, 'Y')
            zlabel(app.yaxis, 'Z')
            app.xaxis = uiaxes(app.UIFigure);
            title(app.xaxis, 'X-slice')
            xlabel(app.xaxis, 'X')
            ylabel(app.xaxis, 'Y')
            zlabel(app.xaxis, 'Z')
            app.zaxis = uiaxes(app.UIFigure);
            title(app.zaxis, 'Z-slice')
            xlabel(app.zaxis, 'X')
            ylabel(app.zaxis, 'Y')
            zlabel(app.zaxis, 'Z')
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = try_0

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end